classdef spike_sorting < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        LeftPanel               matlab.ui.container.Panel
        DataPathEditFieldLabel  matlab.ui.control.Label
        DataPathEditField       matlab.ui.control.EditField
        ViewChannelnButton      matlab.ui.control.Button
        SelectClusterButton     matlab.ui.control.Button
        AddtoClusterxButton     matlab.ui.control.Button
        FunctionListBoxLabel    matlab.ui.control.Label
        FunctionListBox         matlab.ui.control.ListBox
        RunFunctionButton       matlab.ui.control.Button
        DeleteClusterxButton    matlab.ui.control.Button
        ClusterxListBoxLabel    matlab.ui.control.Label
        ClusterxListBox         matlab.ui.control.ListBox
        AddnewClusterButton     matlab.ui.control.Button
        ChannelnDropDownLabel   matlab.ui.control.Label
        ChannelnDropDown        matlab.ui.control.DropDown
        LoadButton              matlab.ui.control.Button
        MarkSelectedOnesButton  matlab.ui.control.StateButton
        RightPanel              matlab.ui.container.Panel
        UIAxes                  matlab.ui.control.UIAxes
    end

    % Properties that correspond to apps with auto-reflow
    properties (Access = private)
        onePanelWidth = 576;
    end

    
    methods (Access = public)
        
        %把所有的spikes都画出来
        function waveformsPlotting(app,option)
            global data;
            global c;
            global colors;
            global n;
            global condition;
            global selectedClusters;
            global ch;
            
            spikes_length = size(data.waveforms{ch},2);
            t = (0.1:0.1:spikes_length/10)';
            
            switch option
                case 'Show all Spikes'
                    
                    condition = 'Show all Spikes';
                    legend(app.UIAxes,'off');
                    cla(app.UIAxes);
                    plot(app.UIAxes,t,data.waveforms{ch}','color','#0072BD');
                    title(app.UIAxes,{'waveforms number:' size(data.waveforms{ch},1)});
                    axis(app.UIAxes, 'tight');
                case 'Show x'
                    app.UIAxes.cla;
                    
                    number = size(app.ClusterxListBox.Value,2);
                    hold(app.UIAxes,"on");
                    selectedClusters = zeros(number,1);
                    condition = 'Show x';
                    c_size = 0;
                    index = [];
                    legendIndex = zeros(1,number) ;
                    legendName = strings(1,number);
                    legend(app.UIAxes,'AutoUpdate','off');
                    for i = 1:number
                        name = split(app.ClusterxListBox.Value(i),' ');
                        switch name{2}
                            case 'Spikes'
                                m = 0;
                            otherwise
                                m = str2num(name{2});
                        end
                        
                        selectedClusters(i) = m;
                        index2add = find(data.idx{ch} == m);
                        if size(index2add,1) == 0
                            continue;
                        end
                        index = [index;index2add];
                        
                        legendName(i) = ['Cluster' num2str(m)];
                        legendIndex(i) = index2add(1);
                        
                        c_size = c_size + size(index2add,1);
                        if m
                            if m > size(colors,1)
                                colors = distinguishable_colors(m,'w');
                            end
                            c(index2add) = plot(app.UIAxes,t,data.waveforms{ch}(index2add,:)','color',colors(m,:));
                        else
                            c(index2add) = plot(app.UIAxes,t,data.waveforms{ch}(index2add,:)','color','#0072BD');
                        end
                    end
                    
                    try
                        legend(app.UIAxes,c(legendIndex),legendName,'LineWidth',1);
                    catch
                        legend(app.UIAxes,'off');
                    end
                    
                    title(app.UIAxes,{'waveforms number:' c_size});
                    hold (app.UIAxes, "off");
                    axis(app.UIAxes, 'tight');
                case 'Show All Clusters'
                    cla(app.UIAxes);
                    colors = distinguishable_colors(n,'w');
                    hold(app.UIAxes,"on");
                    for i = 1:n
                        index = find(data.idx{ch} == i);
                        legend(app.UIAxes,'AutoUpdate','on','LineWidth',1);
                        c(index(1)) = plot(app.UIAxes,t,data.waveforms{ch}(index(1),:)','color',colors(i,:),'DisplayName',['Cluster ' int2str(i)]);
                        if size(index,1)>1
                            legend(app.UIAxes,'AutoUpdate','off');
                            c(index(2:end)) = plot(app.UIAxes,t,data.waveforms{ch}(index(2:end),:)','color',colors(i,:));
                        end
                    end
                    number = length(find(data.idx{ch} ~= 0));
                    title(app.UIAxes,{'waveforms number:' number});
                    %axis(app.UIAxes,[0 spikes_length/10 spikes_min spikes_max]);
                    axis(app.UIAxes, 'tight');
                    hold(app.UIAxes,"off");
                    
                case 'showUnsortedSpikes'
                    condition = 'Unsorted Spikes';
                    cla(app.UIAxes);
                    rest = find(~data.idx{ch});
                    c(rest) = plot(app.UIAxes,t,data.waveforms{ch}(rest,:)','Color','#0072BD');
                    k=length(rest);
                    title(app.UIAxes,{'waveforms number:' k});
                    axis(app.UIAxes, 'tight');
                    
            end
            
        end

        %{
        %spikes重合的点不再重复画
        function waveformsPlotting(app)
            global data;
            spikes_length = size(data.waveforms{ch},2);
            spikes_max = max(data.waveforms{ch},[],'all');
            spikes_min = min(data.waveforms{ch},[],'all');            
            for i = 1:spikes_length
                a = unique(data.waveforms{ch}(:,i));
                scatter(app.UIAxes,i*ones(size(a,1),1),a,".",'MarkerEdgeColor','#0072BD');
                hold on;
                axis([0 spikes_length spikes_min spikes_max]);
            end
            
        end
        
        %}
    end
    
    methods (Access = private)
        
        function addClusters(app,option)
            global n;
            global data;
            global rectangle;
            global c;
            global condition;
            global selectedClusters;
            global colors;
            global ch;
            
            %spikes_length = size(data.waveforms{ch},2);
            index = [];
            
            t1=round(rectangle.Position(1),1)*10;
            t2=round(rectangle.Position(1)+rectangle.Position(3),1)*10;
            v1=rectangle.Position(2)*ones(1,t2-t1+1);
            v2=(rectangle.Position(2)+rectangle.Position(4))*ones(1,t2-t1+1);
            
            switch option
                case 'add to cluster x'
                name = split(app.ClusterxListBox.Value,' ');
                switch name{2}
                    case 'Spikes'
                        cluster2add = 0;
                    otherwise
                        cluster2add = str2num(name{2});
                end
            end
            
            switch condition
                case 'Show All Clusters'
                    index = find(data.idx{ch} ~= 0);
                case 'Show x'
                    for i = 1:size(selectedClusters,1)
                        index = [index;find(data.idx{ch} == selectedClusters(i))];
                    end
                case 'Unsorted Spikes'
                    index = find(data.idx{ch} == 0);
            end
            
            for i = 1:size(index,1)
                if sum(data.waveforms{ch}(index(i),t1:t2) >= v1 & data.waveforms{ch}(index(i),t1:t2) <= v2) >= 1
                    delete(c(index(i)));
                    switch option
                        case 'add to new cluster'
                            data.idx{ch}(index(i)) = n;
                        case 'add to cluster x'
                            data.idx{ch}(index(i)) = cluster2add;
                    end
                end
            end
            
            switch option
                case 'add to new cluster'
                    computeAverage(app,n);
                case 'add to cluster x'
                    computeAverage(app,cluster2add);
            end
            
            %是否某个cluster空了
            switch condition
                case 'Show x'
                    for i = 1:size(selectedClusters,1)
                        if isempty(find(data.idx{ch} == selectedClusters(i), 1)) && selectedClusters ~= 0

                            data.idx{ch}(data.idx{ch}>selectedClusters(i)) = data.idx{ch}(data.idx{ch}>selectedClusters(i))-1;
                            app.ClusterxListBox.Items(:,end) = [];
                            n = n-1;
                            colors = distinguishable_colors(n,'w');
                            
                            %删除平均值图像对应的曲线
                            deleteAverage(app,selectedClusters(i));
                        else
                            computeAverage(app,selectedClusters(i));
                        end
                    end
            end
            
            index = [];
            for i = 1:size(selectedClusters,1)
                index2add = find(data.idx{ch} == selectedClusters(i));
                index = [index;index2add];
            end        
            if size(index,1) 
            axis(app.UIAxes, 'tight');
            end

            delete(rectangle);
            title(app.UIAxes,{'waveforms number:' size(index,1)}); 
            
        end
        
    end
    
    methods (Access = private)
        function Initialization(app)
            global n;
            global f;
            global data;
            global ch;
            
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            n = max(data.idx{ch});
            for i = 1:n
                app.ClusterxListBox.Items{end+1} = ['Cluster ' int2str(i)];
            end
            
            f = figure;
            waveformsPlotting(app,'Show All Clusters');
            
            computeAverage(app,0);
        end
        
        function saveData(app)
            %每次data发生变化后，就重新save一次
            global data;
            global data_path;
            global parameters;
            global X;
            global X_old;
            
            DirectoryPath =fullfile(data_path,'Result\Data');
            if ~exist(DirectoryPath, 'dir')
                mkdir(DirectoryPath)
            end
            
            whereToStore=fullfile(DirectoryPath,'data.mat');
            save(whereToStore,'data','X','parameters','X_old');
            
        end
        
    end
    
    methods (Access = private)
        function deleteAverage(app,option)
            global n;
            global averageWaveforms;
            global colors;
            
            for i = option:n
                averageWaveforms(i,:) = averageWaveforms(i+1,:);
                colors(i,:) = colors(i+1,:);
            end
            averageWaveforms(n+1,:) = [];
            colors(i,:) = [];
            
            drawAverage(app);
        end
        
        function computeAverage(app,option)
            %计算平均值
            %option = 0，时重新计算全部
            %option ~= 0时，只计算option的
            global n;
            global averageWaveforms;
            global data;
            global ch;
            if ~option
                for i = 1:n
                    index = find(data.idx{ch} == i);
                    averageWaveforms(i,:) = sum(data.waveforms{ch}(index,:))/size(index,1);
                end
            else
                index = find(data.idx{ch} == option);
                averageWaveforms(option,:) = sum(data.waveforms{ch}(index,:))/size(index,1);
            end
            drawAverage(app);
        end
    end
    
    
    methods (Access = public)
        function drawAverage(app)
            global n;
            global f;
            global colors;
            global averageWaveforms;
            global ch;
            global data_path;
            
            spikes_length = size(averageWaveforms,2);
            t = (0.1:0.1:spikes_length/10)';
            
            if ~ishandle(f)
                f = figure;
            end
            figure(f);
            cla(f);
            hold on;
            legend('AutoUpdate','on');
            for i = 1:n
                plot(t,averageWaveforms(i,:),'color',colors(i,:),'DisplayName',['Cluster ' int2str(i)],'LineWidth',1);
            end
            
            axis tight;
            xlabel('Time(s)');
            ylabel('Voltage(mV)')
            hold off;
            legend('AutoUpdate',"off");
            
            DirectoryPath = fullfile(data_path,['\Result\Image\ch' num2str(ch-1)]);
            
            whereToStore=fullfile(DirectoryPath,'Cluster_average.png');
            saveas(f,whereToStore);
        end
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            global cMark;
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            [directory,~,~] = fileparts(mfilename('fullpath'));
            cd (directory);
            addpath(genpath('Functions'))
            addpath(genpath('MyFunctions'))
            cMark = plot(app.UIAxes,[0,0],[0,0]);
            delete(cMark);
        end

        % Callback function: ChannelnDropDown, ViewChannelnButton
        function RunSortingButtonPushed(app, event)
            global X;
            global condition;
            global runFlag;
            global ch;
            global X_old;
            
            ch = str2num(app.ChannelnDropDown.Value) + 1;
            condition = "Show All Clusters";
            cla(app.UIAxes);
            
            if ~runFlag(ch)
                runFlag(ch) = 1;
                
                d = uiprogressdlg(app.UIFigure,'Title','Auto Sorting . . .',...
                    'Indeterminate','on');
                
                X = spikeSorting(d,X_old);
                close(d);    
            else
                preprocessing_visualization(X_old{ch},X{ch});
            end
            
            Initialization(app);
            
            runFlag(ch) = 1;
        end

        % Button pushed function: SelectClusterButton
        function SelectClusterButtonPushed(app, event)
            global rectangle;
            global condition;
            if strcmp(condition,'Show all Spikes')
                message = sprintf('Can''t do this option in "Show all Spikes"!');
                uialert(app.UIFigure,message,'Warning',...
                    'Icon','warning');
            else
                rectangle = drawrectangle(app.UIAxes,'Color','r');
            end
        end

        % Button pushed function: AddtoClusterxButton
        function AddtoClusterxButtonPushed(app, event)
            global rectangle;
            
            if isvalid(rectangle)
                addClusters(app,'add to cluster x');
            end
        end

        % Button pushed function: RunFunctionButton
        function RunFunctionButtonPushed(app, event)
            value = app.FunctionListBox.Value;
            global condition;
            global parameters;
            
            app.MarkSelectedOnesButton.Value = false;
                       
            switch value
                case 'Show all Spikes'
                    condition = "Show All spikes";
                    waveformsPlotting(app,'Show all Spikes');
                case 'Show x'
                    waveformsPlotting(app,"Show x");
                case 'Show All Clusters'
                    condition = "Show All Clusters";
                    waveformsPlotting(app,"Show All Clusters");
                case 'Mark x in data'
                    n = size(app.ClusterxListBox.Value,2);
                    option = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        option(i) = str2num(name{2});
                    end
                    Mark_x_all_in_data(option);
                case 'Mark all in data'
                    Mark_x_all_in_data(0);
                case 'View(only) x in data'
                    n = size(app.ClusterxListBox.Value,2);
                    option = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        option(i) = str2num(name{2});
                    end
                     View_only_x_in_data(option);
                case 'x''s frequency'
                    n = size(app.ClusterxListBox.Value,2);
                    Clusters2draw = zeros(n,1);
                    for i = 1:n
                        name = split(app.ClusterxListBox.Value(i),' ');
                        Clusters2draw(i) = str2double(name{2});
                    end
                    frequency_visualization(parameters.length,Clusters2draw);
                case 'all''s frequency'
                    frequency_visualization(parameters.length,0);
            end         
        end

        % Button pushed function: DeleteClusterxButton
        function DeleteClusterxButtonPushed(app, event)
            global data;
            global c;
            global n;
            global condition;
            global ch;
            
            name = split(app.ClusterxListBox.Value,' ');
            m = str2double(name{2});
            
            index = find(data.idx{ch} == m);
            
            data.idx{ch}(index) = 0;
            data.idx{ch}(data.idx{ch}>m) = data.idx{ch}(data.idx{ch}>m)-1;
            app.ClusterxListBox.Items(:,end) = [];
            n = n - 1;
            
            switch condition
                case 'Show All Clusters'
                    hold(app.UIAxes);
                    legend(app.UIAxes,'off');
                    
                    %重新标标签
                    legendarray = c(1:max(data.idx{ch}));
                    legendName = strings(max(data.idx{ch}),1);
                    for i = 1:max(data.idx{ch})
                        legendarray(i) = c(find(data.idx{ch} == i,1));        
                        legendName(i) = ['Cluster' num2str(i)];
                    end
                    legend(app.UIAxes,legendarray,legendName,'LineWidth',1);
                    
                    delete(c(index));
                    
                    number = length(find(data.idx{ch} ~= 0));
                    title(app.UIAxes,{'waveforms number:' number});
                    
                case 'Show x'
                    waveformsPlotting(app,'showUnsortedSpikes');
                    legend(app.UIAxes,'off');
                    app.ClusterxListBox.Value = 'Unsorted Spikes';
            end
            
            deleteAverage(app,m);
        end

        % Button pushed function: AddnewClusterButton
        function AddnewClusterButtonPushed(app, event)
            global n;
            global rectangle;
            global colors;

            if isvalid(rectangle)
                n = n + 1;
                colors = distinguishable_colors(n,'w');
                
                addClusters(app,'add to new cluster');
                app.ClusterxListBox.Items{end+1} = ['Cluster ' int2str(n)];
            end
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
            global data;
            global condition;
            global runFlag;
            global parameters;
            global X_old;
            global data_path;
            global X;
            
            flag = 0;
            
            title(app.UIAxes,'waveforms number:'); 
            legend(app.UIAxes,'off');
            
            data_path = app.DataPathEditField.Value;

            runFlag = zeros(8,1);
            cla(app.UIAxes);

            condition = "Show All Clusters";
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            app.ChannelnDropDown.Items = {};
            
            if isempty(app.DataPathEditField.Value)
                fig = app.UIFigure;
                message = sprintf('path not found');
                uialert(fig,message,'Warning',...
                'Icon','warning');
            else      
                files = dir(fullfile(data_path,'*.abf'));
                if size(files,1) == 0       %如果该文件夹下没有.abf文件
                    files = dir(fullfile(data_path,'*.mat'));
                    if size(files,1) == 0   %如果该文件夹下没有.m文件
                        fig = app.UIFigure;
                        message = sprintf('path not found');
                        uialert(fig,message,'Warning',...
                            'Icon','warning');
                    else                    %如果有.m文件
                        for file = files' 
                            if strcmp(file.name,'data.mat')
                                load(fullfile(data_path,file.name),'data');
                                load(fullfile(data_path,file.name),'parameters');
                                load(fullfile(data_path,file.name),'X');
                                load(fullfile(data_path,file.name),'X_old');
                                flag = 1;
                            end
                        end

                    end                     
                else                        %如果有.abf文件
                    X_old = dataLoad(data_path);
                    data.spiketimes_all = cell(1,8);
                end
                
                for i = 1:size(parameters.channel,2)
                    app.ChannelnDropDown.Items{i} = int2str(parameters.channel(i)-1);
                end
                
                for i = 1:size(parameters.channel,1)
                    DirectoryPath = fullfile(data_path,['\Result\Image\ch' num2str(parameters.channel(i)-1)]);
                    if ~exist(DirectoryPath, 'dir')
                        mkdir(DirectoryPath)
                    end
                    if flag
                        runFlag(parameters.channel(i)) = 1;
                    end
                end
               
            end 
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            global data;
            if ~isempty(data)
                saveData(app);
            end
            delete(app);
        end

        % Value changed function: MarkSelectedOnesButton
        function MarkSelectedOnesButtonValueChanged(app, event)
            value = app.MarkSelectedOnesButton.Value;
            global data;
            global ch;
            global colors;
            global selectedClusters;
            global condition;
            global rectangle;
            global cMark;
            
            if strcmp(condition,'Show x') && value == true && isvalid(rectangle)
                index = [];
                t1=round(rectangle.Position(1),1)*10;
                t2=round(rectangle.Position(1)+rectangle.Position(3),1)*10;
                v1=rectangle.Position(2)*ones(1,t2-t1+1);
                v2=(rectangle.Position(2)+rectangle.Position(4))*ones(1,t2-t1+1);  
                spikes_length = size(data.waveforms{ch},2);
                t = (0.1:0.1:spikes_length/10)';

                for i = 1:size(selectedClusters,1)
                    index = [index;find(data.idx{ch} == selectedClusters(i))];
                end
                
                colorsIndex = (1:size(colors,1))';

                colorsIndex(selectedClusters) = [];

                
                for i = 1:size(index,1)
                    if sum(data.waveforms{ch}(index(i),t1:t2) >= v1 & data.waveforms{ch}(index(i),t1:t2) <= v2) >= 1
                        hold (app.UIAxes, "on");
                        
                        cMark(i) = plot(app.UIAxes,t,data.waveforms{ch}(index(i),:)','color',colors(colorsIndex(1),:));
                    end
                end
                
                delete(rectangle);
                hold (app.UIAxes, "off");
            end        
            
            if strcmp(condition,'Show x') && value == false 
                delete(cMark);
            end        
        end

        % Changes arrangement of the app based on UIFigure width
        function updateAppLayout(app, event)
            currentFigureWidth = app.UIFigure.Position(3);
            if(currentFigureWidth <= app.onePanelWidth)
                % Change to a 2x1 grid
                app.GridLayout.RowHeight = {543, 543};
                app.GridLayout.ColumnWidth = {'1x'};
                app.RightPanel.Layout.Row = 2;
                app.RightPanel.Layout.Column = 1;
            else
                % Change to a 1x2 grid
                app.GridLayout.RowHeight = {'1x'};
                app.GridLayout.ColumnWidth = {233, '1x'};
                app.RightPanel.Layout.Row = 1;
                app.RightPanel.Layout.Column = 2;
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'UI Figure';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIFigure.SizeChangedFcn = createCallbackFcn(app, @updateAppLayout, true);
            app.GridLayout = uigridlayout(app.UIFigure);
            app.LeftPanel = uipanel(app.GridLayout);
            app.DataPathEditFieldLabel = uilabel(app.LeftPanel);
            app.DataPathEditFieldLabel.Text = 'Data Path';
            app.DataPathEditField = uieditfield(app.LeftPanel, 'text');
            app.ViewChannelnButton = uibutton(app.LeftPanel, 'push');
            app.ViewChannelnButton.ButtonPushedFcn = createCallbackFcn(app, @RunSortingButtonPushed, true);
            app.ViewChannelnButton.Text = 'View Channel n';
            app.SelectClusterButton = uibutton(app.LeftPanel, 'push');
            app.SelectClusterButton.ButtonPushedFcn = createCallbackFcn(app, @SelectClusterButtonPushed, true);
            app.SelectClusterButton.Text = 'Select Cluster';
            app.AddtoClusterxButton = uibutton(app.LeftPanel, 'push');
            app.AddtoClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @AddtoClusterxButtonPushed, true);
            app.AddtoClusterxButton.Text = 'Add to Cluster x';
            app.FunctionListBoxLabel = uilabel(app.LeftPanel);
            app.FunctionListBoxLabel.Text = 'Function';
            app.FunctionListBox = uilistbox(app.LeftPanel);
            app.FunctionListBox.Items = {'Show all Spikes', 'Show x', 'Show All Clusters', 'Mark x in data', 'Mark all in data', 'View(only) x in data', 'x''s frequency', 'all''s frequency'};
            app.FunctionListBox.Value = 'Show all Spikes';
            app.RunFunctionButton = uibutton(app.LeftPanel, 'push');
            app.RunFunctionButton.ButtonPushedFcn = createCallbackFcn(app, @RunFunctionButtonPushed, true);
            app.RunFunctionButton.Text = 'Run Function';
            app.DeleteClusterxButton = uibutton(app.LeftPanel, 'push');
            app.DeleteClusterxButton.ButtonPushedFcn = createCallbackFcn(app, @DeleteClusterxButtonPushed, true);
            app.DeleteClusterxButton.Text = 'Delete Cluster x';
            app.ClusterxListBoxLabel = uilabel(app.LeftPanel);
            app.ClusterxListBoxLabel.Text = 'Cluster x';
            app.ClusterxListBox = uilistbox(app.LeftPanel);
            app.ClusterxListBox.Items = {'Unsorted Spikes'};
            app.ClusterxListBox.Multiselect = 'on';
            app.ClusterxListBox.Value = {};
            app.AddnewClusterButton = uibutton(app.LeftPanel, 'push');
            app.AddnewClusterButton.ButtonPushedFcn = createCallbackFcn(app, @AddnewClusterButtonPushed, true);
            app.AddnewClusterButton.Text = 'Add new Cluster';
            app.ChannelnDropDownLabel = uilabel(app.LeftPanel);
            app.ChannelnDropDownLabel.Text = 'Channel n';
            app.ChannelnDropDown = uidropdown(app.LeftPanel);
            app.ChannelnDropDown.Items = {};
            app.ChannelnDropDown.ValueChangedFcn = createCallbackFcn(app, @RunSortingButtonPushed, true);
            app.ChannelnDropDown.Value = {};
            app.LoadButton = uibutton(app.LeftPanel, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.MarkSelectedOnesButton = uibutton(app.LeftPanel, 'state');
            app.MarkSelectedOnesButton.ValueChangedFcn = createCallbackFcn(app, @MarkSelectedOnesButtonValueChanged, true);
            app.MarkSelectedOnesButton.Text = 'MarkSelectedOnes';
            app.RightPanel = uipanel(app.GridLayout);
            app.UIAxes = uiaxes(app.RightPanel);
            title(app.UIAxes, 'Result')
            xlabel(app.UIAxes, 'Time(ms)')
            ylabel(app.UIAxes, 'Voltage (mV)')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = spike_sorting

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end